classdef PlacePrefDataGUI_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlacePrefDataAnalysisUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        Menu3                          matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        ClearCommandWindowMenu         matlab.ui.container.Menu
        ToggleAlwaysOnTopMenu          matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        RelatedProgramsMenu            matlab.ui.container.Menu
        NIDAQmxAudioPlayerMenu         matlab.ui.container.Menu
        AboutthisAppMenu               matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        DataSelectionTab               matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        DatasetToolsPanel              matlab.ui.container.Panel
        DatasetToolsGrid               matlab.ui.container.GridLayout
        BatchAnalysisPanel             matlab.ui.container.Panel
        GridLayout5_2                  matlab.ui.container.GridLayout
        SingleProjectPanel             matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout7                    matlab.ui.container.GridLayout
        CenterPositionHeatmapButton    matlab.ui.control.Button
        GridLayout6                    matlab.ui.container.GridLayout
        DataToolsTrialRawDataSheetButton  matlab.ui.control.Button
        DataToolsTrialVideoPlayButton  matlab.ui.control.Button
        DataToolsTrialDropDown         matlab.ui.control.DropDown
        DataToolsTrialDropDownLabel    matlab.ui.control.Label
        EthoVisionDatasetSelect        FolderSelectorWithDropdown
        MasterMetadataTableDatasetSelect  PathSelector
        StimuliDirDataSelect           PathSelector
        AppStatusBarPanel              matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        AppStatusBarDesc               matlab.ui.control.Label
        AppStatusBarState              matlab.ui.control.Label
    end

    
    properties (Access = private, Hidden)
        % Store the PATH state before doing anything else!!!
        StartupPATH = path;  % The PATH state when the app started. Use for resets.

        % Trigger MATLAB to addpath the components
        dummylib0 = addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'imports')));
        dummylib1 = str2func('load_lib');
        dummycomp = addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'components')));

        AppWindowSize = [960,620]; % The default size of the main app window
    end

    %% Props for Internal Components
    properties (Access = private)
        % MouseMoveCallback (Name-Value): Each value inside should be a cell array 
        %   {@fcnhandle, args1, ...argsN} 
        % to be run in order when mouse move event fires
        % The callback function will have access to the current mouse
        % position relative to the figure by simply define it as the last
        % argument in the function definition
        % Example:
        % function printPos(comp, prefixText, currentPoints)
        % --> Register the callback as {@comp.printPos, prefixText}
        MouseMoveCallbackFcnStack = struct();


        % Store handles to app components here!
        % ...
    end

    %% Props for Internal Use and to be Shared with External Components
    properties (Access = public, Hidden)
        lastDirPath = pwd;

        
    end
    

    %% Props for Internal Data
    properties (Access = private)
        

    end


    %% (Input Args / SetObservable) 
    properties (Access = public, SetObservable, AbortSet)
        AppConfigsPath = ''; % The current app config, loaded from a 'configs.yml' file

    end


    %% Public App Data
    properties (Access = public)
        SubjectMetadata; % Parsed metadata.yml (or similar) of the subject
        SelectedSession; % The selected session and relevant data (stims, etc.)

        SelectedImageStackMetadata; % Metadata struct of the selected image stack by the imaging platfrom
        SelectedStimEvents; % The struct with the stim event timestamps + info
    end

    
    %% Read-only Public App Data
    properties (SetAccess = private, AbortSet)
        Figure; % The handle to this app UIFigure;
    end


    %% Dependent App Data
    properties (Dependent)
        AppConfigs % Configuration loaded from io.config.loadConfigYaml()
    end


    %% METHODS

    methods
        %% For Dependent Props

        function config = get.AppConfigs(obj)
            config = io.config.loadConfigYaml(obj.AppConfigsPath);
        end
    end


    methods (Static)

        function mustBeTextOrEmpty(A)
            %MUSTBETEXTOREMPTY Validate that value is text or empty
            %   MUSTBETEXTOREMPTY(A) throws an error if A is not text or empty.
            
            if isempty(A)
                return;
            end
            
            mustBeText(A);
        end

    end

    methods (Access = private)
        %% Basic UI Utilities

        function color = semanticColor(~, colorOrDesc)
            % Given a color value (rgb, hex, or a semantic description of
            % the color, return a validated MATLAB RGB color value
            % If the color cannot be validated, a default off-white color
            % is returned
            switch colorOrDesc
                case { "reset", "default" }
                    color = [0.94, 0.94, 0.94]; % off-white
                case { "busy", "running", "processing" }
                    color = [0.63, 0.49, 0.72]; % purple-ish
                case { "error", "err" }
                    color = [0.85, 0.50, 0.49]; % red
                case { "warn", "warning" }
                    color = [0.83, 0.58, 0.27]; % dark yellow
                case { "activeGreen" }
                    color = [0.62, 0.82, 0.48]; % light green
                case { "dullGray", "dullGrey" }
                    color = [0.8, 0.8, 0.8]; % dark-ish gray
                
                % More case here as needed!

                otherwise
                    try
                        color = validatecolor(colorOrDesc);
                    catch
                        color = [0.94, 0.94, 0.94];
                    end
            end
        end


        %% Events / Callbacks


        %% Data Selection


    end


    methods (Access = public)
        
        function SetBorderColor(app, color)
            if nargin < 2
                color = 'reset';
            end
            color_rgb = semanticColor(app, color);
            app.GridLayout.BackgroundColor = color_rgb;
        end

        function SetStatusBar(app, status, description)
            arguments
                app
                status {validator.mustBeTextOrEmpty} = ''
                description {validator.mustBeTextOrEmpty} = ''
            end

            if isempty(status)
                status = 'Ready';
            end

            app.AppStatusBarState.Text = string(status);
            app.AppStatusBarDesc.Text = string(description);
            drawnow
        end

        function RegisterMouseMoveCallback(app, callbackID, callback)
            arguments
                app
                callbackID (1,:) {mustBeValidVariableName}
                callback (1,:) {mustBeNonempty}
            end
            app.MouseMoveCallbackFcnStack.(callbackID) = callback;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, RestorePathOnClose)
            % Save this app handle to the workspace so the user have the
            % option to forcefully kill/delete the app when there's
            % something unexpected
            assignin("base", "PlacePreferenceGUI", app);

            if nargin < 2
                RestorePathOnClose = '';
            end
            if ~isempty(RestorePathOnClose) && (class(RestorePathOnClose) == "string" || class(RestorePathOnClose) == "char" || iscellstr(RestorePathOnClose))
                app.StartupPATH = RestorePathOnClose;
            end

            %% Show loader until the loading is done to avoid early interaction and obvious layout shift
            app.AppStatusBarState.Text = "INITIALIZING";
            app.AppStatusBarDesc.Text = "Starting up...";
            startupLoader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Initializing',...
                'Message',"Starting up the Program...", ...
                "Indeterminate", "on");
            drawnow

            
            %% Set App's Defaults and Initialize Variables
            app.Figure = app.PlacePrefDataAnalysisUIFigure;

            screensize = get(0,'screensize');
            screenW = screensize(3); screenH = screensize(4);
            if screenW < app.AppWindowSize(1)
                app.AppWindowSize(1) = screenW;
            end
            if screenH < app.AppWindowSize(2)
                app.AppWindowSize(2) = screenH;
            end


            %% Set Hidden Components
            app.BatchAnalysisPanel.Parent = [];
            app.DatasetToolsGrid.RowHeight = {"1x"};
            app.BatchAnalysisPanel.Layout.Row = 1;
            app.SingleProjectPanel.Layout.Row = 1;
            
            
            %% Load components, modules, and utilities
            startupLoader.Message = "Importing App's Dependencies";

            % Load external + prioritized libs first
            thismfileDir = fileparts(mfilename('fullpath'));
            run(fullfile(thismfileDir, 'imports', 'load_lib.m'))
            
            app.PlacePrefDataAnalysisUIFigure.Position = [(screenW-app.AppWindowSize(1))/2, (screenH-app.AppWindowSize(2))/2, app.AppWindowSize(1), app.AppWindowSize(2)];
            drawnow;


            % Make sure to set the current mfiledir back to the main mlapp
            thismfileDir = fileparts(mfilename('fullpath'));
            % Add main app's required paths
            requiredPaths = {'assets', 'utils', 'modules', 'callbacks'};
            for i=1:length(requiredPaths)
                thisdir = fullfile(thismfileDir, requiredPaths{i});
                if ~isfolder(thisdir), continue; end
                reqDir = dir(thisdir);
                reqDir = reqDir(~ismember({reqDir.name}, {'.', '..'}));
                reqDir = reqDir(arrayfun(@(x) x.isdir, reqDir));
                srcDirPaths = fullfile({reqDir.folder}, {reqDir.name});
                for j=1:length(srcDirPaths)
                    startupLoader.Message = ['Importing ', fullfile('src', reqDir(j).name)];
                    paths = genpath(srcDirPaths{j});
                    addpath(paths)
                end
            end


            %% Mount/Use components or modules after loading


            %% Attempt to load the user-defined Preference File


            %% Register Components' Static Functions
            app.EthoVisionDatasetSelect.AppFigure = app.PlacePrefDataAnalysisUIFigure;
            app.EthoVisionDatasetSelect.DropdownItemsFilterFcn = {@io.ethovision.filterProjectFolder};


            %% Add listeners for Observable props
            % Set these at the end so that we have the option to initialize
            % the values differently above as needed
            % addlistener(app, 'AppConfigsPath', ...
            %     'PostSet',@app.ConfigFilePathUpdatedFcn ...
            % );


            %% Set default values from config
            if isfile(fullfile(fileparts(mfilename('fullpath')), '..', 'configs.yaml'))
                app.AppConfigsPath = char(fullfile(fileparts(mfilename('fullpath')), '..', 'configs.yaml'));
            end
            if isfile(fullfile(fileparts(mfilename('fullpath')), '..', 'configs.yml'))
                app.AppConfigsPath = char(fullfile(fileparts(mfilename('fullpath')), '..', 'configs.yml'));
            end

            if isstruct(app.AppConfigs) && ~isempty(fieldnames(app.AppConfigs))
                startupLoader.Message = "Loading Config File";
                app.StimuliDirDataSelect.SelectedPaths = fullfile(app.AppConfigs.CONFIG_ROOT, app.AppConfigs.defaults.stimuli_directory);
                app.MasterMetadataTableDatasetSelect.SelectedPaths = fullfile(app.AppConfigs.CONFIG_ROOT, app.AppConfigs.defaults.master_metadata_table);
                app.EthoVisionDatasetSelect.SelectedParent = fullfile(app.AppConfigs.CONFIG_ROOT, app.AppConfigs.defaults.ethovision_data_root);
                app.EthoVisionDatasetSelect.refreshDropdown();
            end


            %% Clear the loader at the end of startup
            startupLoader.Indeterminate = 'off';
            startupLoader.Message = 'Ready!';
            startupLoader.Value = 1;
            SetStatusBar(app);
            close(startupLoader);
        end

        % Close request function: PlacePrefDataAnalysisUIFigure
        function PlacePrefDataAnalysisUIFigureCloseRequest(app, event)
            loader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Cleaning up...',...
                'Message',"Cleaning up environment before closing...", ...
                "Indeterminate", "on");
            drawnow
            
            % Do clean up tasks of components/modules here before reset PATH


            evalin('base', 'clear(''PlacePreferenceGUI'')' );
            
            % Reset the PATH to how it was before startup
            path(app.StartupPATH);

            close(loader);
            delete(app);
        end

        % Window button motion function: PlacePrefDataAnalysisUIFigure
        function PlacePrefDataAnalysisUIFigureWindowButtonMotion(app, event)
            if isempty(app.MouseMoveCallbackFcnStack) || ~isstruct(app.MouseMoveCallbackFcnStack) || isempty(fieldnames(app.MouseMoveCallbackFcnStack))
                return
            end
            currentFPoints = get(app.Proc2PCIUIFigure, 'CurrentPoint');
            keys = fieldnames(app.MouseMoveCallbackFcnStack);
            for i=1:length(keys)
                thiskey = keys{i};
                thisfcn = app.MouseMoveCallbackFcnStack.(thiskey);
                if isempty(thisfcn)
                    continue
                end

                try
                    argCount = abs(nargin(thisfcn{1}));
                    
                    if argCount == length(thisfcn)
                        % Inject the current position as the last arg if the
                        % function supports it
                        feval(thisfcn{:}, currentFPoints);
                    else
                        % Otherwise, run the callback function as is
                        feval(thisfcn{:})
                    end

                catch ME
                    warning("Failed to run MouseMoveCallbackFcn: %s\n%s", func2str(thisfcn{1}), ME.getReport());
                end
            end
        end

        % Menu selected function: NIDAQmxAudioPlayerMenu
        function NIDAQmxAudioPlayerMenuSelected(app, event)
            web("https://github.com/codynhanpham/nidaq_audioplayer");
        end

        % Menu selected function: ClearCommandWindowMenu
        function ClearCommandWindowMenuSelected(app, event)
            clc;
        end

        % Menu selected function: ToggleAlwaysOnTopMenu
        function ToggleAlwaysOnTopMenuSelected(app, event)
            currentWindowStyle = app.PlacePrefDataAnalysisUIFigure.WindowStyle;
            if currentWindowStyle == "normal"
                app.PlacePrefDataAnalysisUIFigure.WindowStyle = 'alwaysontop';
                app.ToggleAlwaysOnTopMenu.Checked = "on";
            elseif currentWindowStyle == "alwaysontop"
                app.PlacePrefDataAnalysisUIFigure.WindowStyle = 'normal';
                app.ToggleAlwaysOnTopMenu.Checked = "off";
            end
        end

        % Callback function: MasterMetadataTableDatasetSelect
        function MasterMetadataTableDatasetSelectPathsSelected(app, event)
            selected = app.MasterMetadataTableDatasetSelect.SelectedPaths;
            selected = char(selected); % this is okay since the component is set to only allow single value

            if ~endsWith(selected, '.xlsx')
                uialert(app.PlacePrefDataAnalysisUIFigure, "Master Metadata Table must be an Excel file with the '.xlsx' extension.", "Invalid Selection", "Icon", "error");
                app.MasterMetadataTableDatasetSelect.SelectedPaths = {};
            end
        end

        % Callback function: EthoVisionDatasetSelect
        function EthoVisionDatasetSelectContentDropDownValueChanged(app, event)
            selected = app.EthoVisionDatasetSelect.SelectedContent;

            switch selected.type
                case "single"
                    app.BatchAnalysisPanel.Parent = [];
                    app.SingleProjectPanel.Parent = app.DatasetToolsGrid;

                    [trialNames, trialsInfo] = io.ethovision.filterTrials(fullfile(selected.dir.folder, selected.dir.name));
                    app.DataToolsTrialDropDown.Items = trialNames;
                    app.DataToolsTrialDropDown.ItemsData = trialsInfo;

                case "batch"
                    app.SingleProjectPanel.Parent = [];
                    app.BatchAnalysisPanel.Parent = app.DatasetToolsGrid;


            end
        end

        % Button pushed function: DataToolsTrialVideoPlayButton
        function DataToolsTrialVideoPlayButtonPushed(app, event)
            trial = app.DataToolsTrialDropDown.Value;

            loader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Launching Video Player...',...
                'Message',"Initializing tracked video player...", ...
                "Indeterminate", "on");
            drawnow;
            graphics.trialMediaPlayer("VideoFile", trial.media, "EthovisionXlsx", trial.data, "ImgWidthFOV_cm", app.AppConfigs.project_settings.EthoVision.CameraImgWidthFOV_cm);
            close(loader);
        end

        % Button pushed function: DataToolsTrialRawDataSheetButton
        function DataToolsTrialRawDataSheetButtonPushed(app, event)
            trial = app.DataToolsTrialDropDown.Value;
            if (ispc)
                winopen(trial.data);
            elseif ismac
                cmdToExecute = ['open ' trial.data];
                [status, path] = system(cmdToExecute); %#ok<ASGLU>
            else
                cmdToExecute = ['xdg-open ' trial.data];
                [status, path] = system(cmdToExecute); %#ok<ASGLU>
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PlacePrefDataAnalysisUIFigure = uifigure('Visible', 'off');
            app.PlacePrefDataAnalysisUIFigure.Name = 'PlacePref Data Analysis';
            app.PlacePrefDataAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @PlacePrefDataAnalysisUIFigureCloseRequest, true);
            app.PlacePrefDataAnalysisUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @PlacePrefDataAnalysisUIFigureWindowButtonMotion, true);
            app.FileMenu = uimenu(app.PlacePrefDataAnalysisUIFigure);
            app.FileMenu.Text = '  File  ';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.FileMenu);
            app.Menu2.Text = 'Menu2';
            app.Menu3 = uimenu(app.FileMenu);
            app.Menu3.Text = 'Menu3';
            app.ViewMenu = uimenu(app.PlacePrefDataAnalysisUIFigure);
            app.ViewMenu.Text = '  View  ';
            app.ClearCommandWindowMenu = uimenu(app.ViewMenu);
            app.ClearCommandWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearCommandWindowMenuSelected, true);
            app.ClearCommandWindowMenu.Text = 'Clear Command Window';
            app.ToggleAlwaysOnTopMenu = uimenu(app.ViewMenu);
            app.ToggleAlwaysOnTopMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleAlwaysOnTopMenuSelected, true);
            app.ToggleAlwaysOnTopMenu.Separator = 'on';
            app.ToggleAlwaysOnTopMenu.Text = 'Toggle Always On Top';
            app.HelpMenu = uimenu(app.PlacePrefDataAnalysisUIFigure);
            app.HelpMenu.Text = '  Help  ';
            app.RelatedProgramsMenu = uimenu(app.HelpMenu);
            app.RelatedProgramsMenu.Text = 'Related Programs';
            app.NIDAQmxAudioPlayerMenu = uimenu(app.RelatedProgramsMenu);
            app.NIDAQmxAudioPlayerMenu.MenuSelectedFcn = createCallbackFcn(app, @NIDAQmxAudioPlayerMenuSelected, true);
            app.NIDAQmxAudioPlayerMenu.Tooltip = {'The software for playing audio stimluli and sending triggers.'; ''; 'It can also be use to create a composite audio file from a playlist with sub-milisecond-accuracy chapter timestamps.'; ''; '(Click to view on GitHub)'};
            app.NIDAQmxAudioPlayerMenu.Text = 'NI-DAQmx Audio Player';
            app.AboutthisAppMenu = uimenu(app.HelpMenu);
            app.AboutthisAppMenu.Separator = 'on';
            app.AboutthisAppMenu.Text = 'About this App...';
            app.GridLayout = uigridlayout(app.PlacePrefDataAnalysisUIFigure);
            app.AppStatusBarPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.AppStatusBarPanel);
            app.AppStatusBarState = uilabel(app.GridLayout2);
            app.AppStatusBarState.Text = 'Ready';
            app.AppStatusBarDesc = uilabel(app.GridLayout2);
            app.AppStatusBarDesc.Text = '';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.DataSelectionTab = uitab(app.TabGroup);
            app.DataSelectionTab.Title = 'Data Selection';
            app.GridLayout3 = uigridlayout(app.DataSelectionTab);
            app.StimuliDirDataSelect = PathSelector(app.GridLayout3);
            app.StimuliDirDataSelect.BorderSize = 2;
            app.StimuliDirDataSelect.PanelTitle = 'Stimuli Playlist Location';
            app.StimuliDirDataSelect.PanelTitleFontSize = 16;
            app.StimuliDirDataSelect.PanelTitlePosition = 'centertop';
            app.StimuliDirDataSelect.Placeholder = 'Path to a directory containing some audio files. This will recursively search up to 3 levels deep for matching audio files.';
            app.StimuliDirDataSelect.SelectMultiple = false;
            app.StimuliDirDataSelect.SelectType = 'folder';
            app.MasterMetadataTableDatasetSelect = PathSelector(app.GridLayout3);
            app.MasterMetadataTableDatasetSelect.BorderSize = 2;
            app.MasterMetadataTableDatasetSelect.PanelTitle = 'Master Metadata Table';
            app.MasterMetadataTableDatasetSelect.PanelTitleFontSize = 16;
            app.MasterMetadataTableDatasetSelect.PanelTitlePosition = 'centertop';
            app.MasterMetadataTableDatasetSelect.Placeholder = 'Path to an *.xlsx file';
            app.MasterMetadataTableDatasetSelect.SelectMultiple = false;
            app.MasterMetadataTableDatasetSelect.PathsSelectedFcn = createCallbackFcn(app, @MasterMetadataTableDatasetSelectPathsSelected, true);
            app.EthoVisionDatasetSelect = FolderSelectorWithDropdown(app.GridLayout3);
            app.EthoVisionDatasetSelect.BorderSize = 2;
            app.EthoVisionDatasetSelect.DropDownLabel = 'Datasets';
            app.EthoVisionDatasetSelect.DropDownPlaceholder = 'EthoVision Project';
            app.EthoVisionDatasetSelect.DropdownSortAscend = false;
            app.EthoVisionDatasetSelect.PanelTitle = 'EthoVision Datasets';
            app.EthoVisionDatasetSelect.PanelTitleFontSize = 16;
            app.EthoVisionDatasetSelect.PanelTitlePosition = 'centertop';
            app.EthoVisionDatasetSelect.Placeholder = 'Path to EthoVision projects root. Must contain some EthoVision project subfolder as saved by EthoVision.';
            app.EthoVisionDatasetSelect.ContentDropDownValueChangedFcn = createCallbackFcn(app, @EthoVisionDatasetSelectContentDropDownValueChanged, true);
            app.DatasetToolsPanel = uipanel(app.GridLayout3);
            app.DatasetToolsPanel.Title = 'Dataset Tools';
            app.DatasetToolsGrid = uigridlayout(app.DatasetToolsPanel);
            app.SingleProjectPanel = uipanel(app.DatasetToolsGrid);
            app.GridLayout5 = uigridlayout(app.SingleProjectPanel);
            app.DataToolsTrialDropDownLabel = uilabel(app.GridLayout5);
            app.DataToolsTrialDropDownLabel.Text = 'Trial # ';
            app.DataToolsTrialDropDown = uidropdown(app.GridLayout5);
            app.DataToolsTrialDropDown.Items = {};
            app.DataToolsTrialDropDown.Placeholder = 'EthoVision Trial Number';
            app.DataToolsTrialDropDown.Value = {};
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.DataToolsTrialVideoPlayButton = uibutton(app.GridLayout6, 'push');
            app.DataToolsTrialVideoPlayButton.ButtonPushedFcn = createCallbackFcn(app, @DataToolsTrialVideoPlayButtonPushed, true);
            app.DataToolsTrialVideoPlayButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'play.svg');
            app.DataToolsTrialVideoPlayButton.Text = 'Play Tracked Video';
            app.DataToolsTrialRawDataSheetButton = uibutton(app.GridLayout6, 'push');
            app.DataToolsTrialRawDataSheetButton.ButtonPushedFcn = createCallbackFcn(app, @DataToolsTrialRawDataSheetButtonPushed, true);
            app.DataToolsTrialRawDataSheetButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'file-spreadsheet.svg');
            app.DataToolsTrialRawDataSheetButton.Text = 'View Raw Data Table';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.CenterPositionHeatmapButton = uibutton(app.GridLayout7, 'push');
            app.CenterPositionHeatmapButton.Text = 'Center Position Heatmap';
            app.BatchAnalysisPanel = uipanel(app.DatasetToolsGrid);
            app.GridLayout5_2 = uigridlayout(app.BatchAnalysisPanel);
            % Show the figure after all components are created
            app.PlacePrefDataAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlacePrefDataGUI_main(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PlacePrefDataAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PlacePrefDataAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlacePrefDataAnalysisUIFigure)
        end
    end
end